classdef eyeCalibration < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        VideoCollectionTab              matlab.ui.container.Tab
        CameraParametersLabel           matlab.ui.control.Label
        PresetsDropDownLabel            matlab.ui.control.Label
        PresetsDropDown                 matlab.ui.control.DropDown
        CameraResolutionEditFieldLabel  matlab.ui.control.Label
        CameraResolutionEditField       matlab.ui.control.NumericEditField
        BoxSizeEditFieldLabel           matlab.ui.control.Label
        BoxSizeEditField                matlab.ui.control.NumericEditField
        BoxOffsetEditFieldLabel         matlab.ui.control.Label
        BoxOffsetEditField              matlab.ui.control.NumericEditField
        CameraResolutionEditField_2     matlab.ui.control.NumericEditField
        UIAxes                          matlab.ui.control.UIAxes
        UIAxes_2                        matlab.ui.control.UIAxes
        SetupCamerasButton              matlab.ui.control.Button
        CollectDataButton               matlab.ui.control.Button
        NumberofframestocollectEditFieldLabel  matlab.ui.control.Label
        NumberofframestocollectEditField  matlab.ui.control.NumericEditField
        DoneSetupButton                 matlab.ui.control.Button
        OfflineEyeTrackingTab           matlab.ui.container.Tab
        RadiusPupilEditFieldLabel       matlab.ui.control.Label
        RadiusPupilEditField            matlab.ui.control.NumericEditField
        XPaddingEditFieldLabel          matlab.ui.control.Label
        XPaddingEditField               matlab.ui.control.NumericEditField
        YPaddingEditFieldLabel          matlab.ui.control.Label
        YPaddingEditField               matlab.ui.control.NumericEditField
        RadiusCornealReflection1EditFieldLabel  matlab.ui.control.Label
        RadiusCornealReflection1EditField  matlab.ui.control.NumericEditField
        RadiusCornealReflection2Label   matlab.ui.control.Label
        RadiusCornealReflection2EditField  matlab.ui.control.NumericEditField
        MinimumFeaturesEditFieldLabel   matlab.ui.control.Label
        MinimumFeaturesEditField        matlab.ui.control.NumericEditField
        radiiCR1EditField_2             matlab.ui.control.NumericEditField
        radiiCR2EditField_2             matlab.ui.control.NumericEditField
        ManualPupilLocationSelectionCheckBox  matlab.ui.control.CheckBox
        ImproveImageContrastCheckBox    matlab.ui.control.CheckBox
        TestParametersButton            matlab.ui.control.Button
        RunAnalysisButton               matlab.ui.control.Button
        edgeThreshCam1EditFieldLabel    matlab.ui.control.Label
        edgeThreshCam1EditField         matlab.ui.control.NumericEditField
        edgeThreshCam2EditFieldLabel    matlab.ui.control.Label
        edgeThreshCam2EditField         matlab.ui.control.NumericEditField
        PlotAnalysisCheckBox            matlab.ui.control.CheckBox
        debugCheckBox                   matlab.ui.control.CheckBox
        UIAxes2                         matlab.ui.control.UIAxes
        UIAxes2_2                       matlab.ui.control.UIAxes
        UIAxes3                         matlab.ui.control.UIAxes
        UIAxes3_2                       matlab.ui.control.UIAxes
        EyeAnalysisTab                  matlab.ui.container.Tab
        CalibrationStimFrequencyEditFieldLabel  matlab.ui.control.Label
        CalibrationStimFrequencyEditField  matlab.ui.control.NumericEditField
        LeftEyeCheckBox                 matlab.ui.control.CheckBox
        Camera2TimestampsCheckBox       matlab.ui.control.CheckBox
        Step1AnalyzeDataPanel           matlab.ui.container.Panel
        findmagscaleVelButton           matlab.ui.control.Button
        SaccadeWindowEditField_2        matlab.ui.control.NumericEditField
        SaccadeThresholdVideoEditFieldLabel  matlab.ui.control.Label
        SaccadeThresholdVideoEditField  matlab.ui.control.NumericEditField
        SaccadeWindowmsEditFieldLabel   matlab.ui.control.Label
        SaccadeWindowmsEditField        matlab.ui.control.NumericEditField
        SaccadeThresholdMagnetEditFieldLabel  matlab.ui.control.Label
        SaccadeThresholdMagnetEditField  matlab.ui.control.NumericEditField
        MinimumGoodDataLengthEditFieldLabel  matlab.ui.control.Label
        MinimumGoodDataLengthEditField  matlab.ui.control.NumericEditField
        AlignBestSegmentCheckBox        matlab.ui.control.CheckBox
        Step2PlotDataPanel              matlab.ui.container.Panel
        PLOT_DesaccededTracesButton     matlab.ui.control.Button
        PLOT_FramerateHistogramButton   matlab.ui.control.Button
        PlotAlignedTracesButton         matlab.ui.control.Button
        PlotLinearityTracesButton       matlab.ui.control.Button
        PlotDownsampleShortScatterPlotsButton  matlab.ui.control.Button
        LinearRegressiononAllDataButton  matlab.ui.control.Button
        LienarRegressiononEachSegmentButton  matlab.ui.control.Button
    end

    
    properties (Access = private)
        vars % Description
    end
    methods (Access = private)
        
        function vidAmp = vidTimeFcn(app, tvid,vidH,freq,tscale)
            varsTemp = [sin(2*pi*freq*tvid(:)/tscale) cos(2*pi*freq*tvid(:)/tscale) ones(size(tvid(:)))];
            yTemp = [0; diff(vidH)]/mean(diff(tvid));
            mask = abs(yTemp)<100;
            bVidTemp = regress(yTemp(mask), varsTemp(mask,:));
            vidAmp = -sqrt(bVidTemp(1)^2+bVidTemp(2)^2);
        end
    
        function findmagscaleVelAPP(app)
            
            freq = app.CalibrationStimFrequencyEditField.Value;
            
            
            %%%%% LOAD MAGNET
            pathname = cd;
            [~, filenameroot]= fileparts(pathname);
            fullfilename = fullfile(pathname,[filenameroot '.smr']);
            
            if app.LeftEyeCheckBox.Value
                % Left Eye (default)
                app.vars.magnetData = importSpike(fullfilename,[4 5 6 10]);
            else
                % Right Eye
                app.vars.magnetData = importSpike(fullfilename,[4 7 8 10]);
            end
            
            %%%%% LOAD VIDEO
            A = load(fullfile(pathname, 'videoresults.mat'));
            app.vars.vidResults = A.results;
            [app.vars.vidH, app.vars.vidV, ~] = calceyeangle(app.vars.vidResults);
            app.vars.percent_frames_missed = sum(int64(isnan(app.vars.vidH)))*100/length(app.vars.vidH);
            if app.Camera2TimestampsCheckBox.Value
                % Camera 2 (default)
                app.vars.tvid = app.vars.vidResults.time2;
            else
                % Camera 1
                app.vars.tvid = app.vars.vidResults.time1;
            end
            app.vars.tvid = app.vars.tvid-app.vars.tvid(1);
            [app.vars.tscale, temp] = fminsearchbnd(@(x)vidTimeFcn(app, app.vars.tvid,app.vars.vidH,freq,x),1,.7, 1.4);
            app.vars.tvid = app.vars.tvid/app.vars.tscale;%.995;
            app.vars.samplerate_Video = 1/mean(diff(app.vars.tvid));
                       
            
            %%%%% Select Proper Segment 
            app.vars.lightpulse = app.vars.magnetData(end).data;
            app.vars.seg(1) = app.vars.lightpulse(1);
            app.vars.seg(2) = app.vars.seg(1)+app.vars.tvid(end);
            
            app.vars.magnetSeg = resettime(datseg(app.vars.magnetData,app.vars.seg));
            app.vars.samplerate_Magnet = app.vars.magnetSeg(1).samplerate;
            app.vars.magnetSeg(1).data = smooth([diff(smooth(app.vars.magnetSeg(1).data,25)); 0]*app.vars.samplerate_Magnet,25); % Convert to velocity
            app.vars.magnetSeg(1).units = 'deg/s'; app.vars.magnetSeg(1).chanlabel = 'hhvel';
           
            app.vars.mag1 = app.vars.magnetSeg(2);
            app.vars.mag1.data = double(app.vars.mag1.data);
            app.vars.mag2 = app.vars.magnetSeg(3);
            app.vars.mag2.data = double(app.vars.mag2.data);
            app.vars.tmag = dattime(app.vars.mag1);
            
            %%%%% Upsample Video Traces
            app.vars.vidH_upsample = interp1(app.vars.tvid,app.vars.vidH,app.vars.tmag(:),'linear');
            app.vars.vidH_upsample = inpaint_nans(app.vars.vidH_upsample);
            app.vars.vidV_upsample = interp1(app.vars.tvid,app.vars.vidV,app.vars.tmag(:),'linear');
            app.vars.vidV_upsample = inpaint_nans(app.vars.vidV_upsample);
                        
            %%%%% Desaccade 
            windowPre = app.SaccadeWindowmsEditField.Value;
            windowPost = app.SaccadeWindowEditField_2.Value;
            minDataLength = app.MinimumGoodDataLengthEditField.Value;
            
            threshMag = app.SaccadeThresholdMagnetEditField.Value;
            threshVid = app.SaccadeThresholdVideoEditField.Value;
                            
            [app.vars.sacLoc_mag1, ~, app.vars.mag1Vel] = desaccadeVel_A(app.vars.mag1.data, app.vars.samplerate_Magnet, 1, windowPre, windowPost, threshMag, minDataLength);
            title('Magnet 1 (Unscaled!)')
            [app.vars.sacLoc_mag2, ~, app.vars.mag2Vel] = desaccadeVel_A(app.vars.mag2.data, app.vars.samplerate_Magnet, 1, windowPre, windowPost, threshMag, minDataLength);
            title('Magnet 2 (Unscaled!)')
            [app.vars.sacLoc_vid , ~, app.vars.vidVel]  = desaccadeVel_A(app.vars.vidH_upsample,app.vars.samplerate_Magnet, 1, windowPre, windowPost, threshVid, minDataLength);
            title('Video')

                
            %%%%% SINE FIT FOR MAGET AND VIDEO
            y1 = sin(2*pi*freq*app.vars.tmag(:));
            y2 = cos(2*pi*freq*app.vars.tmag(:));
            const = ones(size(y1));
            app.vars.vars = [y1 y2 const];
            
            % ------------ Chair -------------
            [app.vars.bHead,~,~,~,~] = regress(app.vars.magnetSeg(1).data, app.vars.vars);
            headAmp = sqrt(app.vars.bHead(1)^2+app.vars.bHead(2)^2);
            app.vars.headAmp = headAmp;
            headPhase = rad2deg(atan2(app.vars.bHead(2),app.vars.bHead(1)));
            
            % ------------ MAGNET 1------------
            [app.vars.bMag1,~,~,~,stat] = regress(app.vars.mag1Vel(~app.vars.sacLoc_mag1), app.vars.vars(~app.vars.sacLoc_mag1,:));
            mag1Amp = sqrt(app.vars.bMag1(1)^2+app.vars.bMag1(2)^2);
            app.vars.mag1Amp = mag1Amp;
            mag1Phase = mod((rad2deg(atan2(app.vars.bMag1(2),app.vars.bMag1(1))) - headPhase),360)-180;
            r2mag1 = stat(1);
            app.vars.r2mag1 = r2mag1;
            
            % ------------ MAGNET 2------------
            [app.vars.bMag2,~,~,~,stat] = regress(app.vars.mag2Vel(~app.vars.sacLoc_mag2), app.vars.vars(~app.vars.sacLoc_mag2,:));
            mag2Amp = sqrt(app.vars.bMag2(1)^2+app.vars.bMag2(2)^2);
            app.vars.mag2Amp = mag2Amp;
            mag2Phase = mod((rad2deg(atan2(app.vars.bMag2(2),app.vars.bMag2(1))) - headPhase),360)-180;
            r2mag2 = stat(1);
            app.vars.r2mag2 = r2mag2;
            
            % ------------ VIDEO ------------
            [app.vars.bVid,~,~,~,stat] = regress(app.vars.vidVel(~app.vars.sacLoc_vid), app.vars.vars(~app.vars.sacLoc_vid,:));
            vidAmp = sqrt(app.vars.bVid(1)^2+app.vars.bVid(2)^2);
            app.vars.vidAmp = vidAmp;
            vidPhase = rad2deg(atan2(app.vars.bVid(2), app.vars.bVid(1)));
            r2vid = stat(1);
            app.vars.r2vid = r2vid;
            
            
            %%%%% SAVE SCALE FACTOR
            % Add term for 180 deg phase - negate?
            if r2mag1>r2mag2
                scaleCh1 = vidAmp/mag1Amp * (2*(abs(mag1Phase)<90)-1); % Change sign if needed
                app.vars.scaleCh1 = scaleCh1;
                scaleCh2 = 0;
                app.vars.scaleCh2 = scaleCh1;
                app.vars.chosenMag = app.vars.mag1.data; 
                app.vars.chosenMagVel = app.vars.mag1Vel;
                app.vars.chosenScale = scaleCh1;
                app.vars.chosenMagSacLoc = app.vars.sacLoc_mag1;
            else
                scaleCh1 = 0;
                app.vars.scaleCh1 = scaleCh1;
                scaleCh2 = vidAmp/mag2Amp * (2*(abs(mag2Phase)<90)-1); % Change sign if needed
                app.vars.scaleCh2 = scaleCh2;
                app.vars.chosenMag = app.vars.mag2.data; 
                app.vars.chosenMagVel = app.vars.mag2Vel;
                app.vars.chosenScale = scaleCh2;
                app.vars.chosenMagSacLoc = app.vars.sacLoc_mag2;
            end
            fprintf('Scale: Chan 1 = %.2f\nScale: Chan 2 = %.2f\n',app.vars.scaleCh1, app.vars.scaleCh2)
            fprintf('r^2: Chan 1 = %.4f\nr^2: Chan 2 = %.4f\n',r2mag1, r2mag2)
            save(fullfile(cd, [filenameroot '.mat']),'scaleCh1', 'scaleCh2',...
                'vidAmp','mag1Amp','mag1Phase','mag2Amp','mag2Phase',...
                'r2mag1','r2mag2','r2vid','threshVid','threshMag','freq');   
            
            %%%%% ALIGN TRACES IN TIME
            AlignMagnetAndVideo(app);
                        
        end
    
        function fit = desaccadePrep(app, data)
            
            segTime_m1 = 0:(1/app.vars.samplerate_Magnet):(length(data)-1)/app.vars.samplerate_Magnet;
            segLength_m1 = length(data);
            
            y1 = sin(2*pi*1*segTime_m1(:));
            y2 = cos(2*pi*1*segTime_m1(:));
            constant = ones(segLength_m1,1);
            varsTEMP = [y1 y2 constant];
            keep = abs(data) < 5*std(abs(data)) + mean(abs(data));
            
            b = regress(data(keep), varsTEMP(keep,:));
            fit = varsTEMP * b;
        end
        
        function AlignMagnetAndVideo(app)
                
            if app.AlignBestSegmentCheckBox.Value
                
                app.vars.keep = ~(app.vars.sacLoc_mag1|app.vars.sacLoc_mag2|app.vars.sacLoc_vid);
                app.vars.keep_start = strfind(app.vars.keep', [0 1]);
                app.vars.keep_end = strfind(app.vars.keep', [1 0]);
                
                %if isempty(keep_start) && isempty(keep_end)
                %    return
                %end
                
                if isempty(app.vars.keep_start) && ~isempty(app.vars.keep_end)
                    app.vars.keep_start = 1;
                    
                elseif ~isempty(app.vars.keep_start) && isempty(app.vars.keep_end)
                    app.vars.keep_end = length(app.vars.keep);
                    
                elseif ~isempty(app.vars.keep_end) && ~isempty(app.vars.keep_start)
                    if app.vars.keep_end(1) < app.vars.keep_start(1)
                        app.vars.keep_start = [1 app.vars.keep_start];
                    end
                    
                    if app.vars.keep_end(end) < app.vars.keep_start(end)
                        app.vars.keep_end = [app.vars.keep_end length(app.vars.keep)];
                    end
                end
                
                best_stretch = find(app.vars.keep_end-app.vars.keep_start == max(app.vars.keep_end-app.vars.keep_start));
                best_mag = app.vars.chosenMag(app.vars.keep_start(best_stretch):app.vars.keep_end(best_stretch));
                best_vid = app.vars.vidH_upsample(app.vars.keep_start(best_stretch):app.vars.keep_end(best_stretch));
                
                [c,lags] = crosscorr(best_mag,best_vid,'NumLags',length(best_mag)-1);
                lag = lags(find(c==max(c)));
                lag = lag - 1000; % General trend seems to be this slignment method will be one phase off
                
            else % align enitre traces (fails with excessive noise)
                
                [c,lags] = crosscorr(app.vars.chosenMag,app.vars.vidH_upsample,'NumLags',length(app.vars.chosenMag)-1);
                lag = lags(find(c==max(c)));
                
            end
            
            if lag < 0
                app.vars.mag_aligned = app.vars.chosenMag((-lag)+1:end);
                app.vars.mag_keep = ~app.vars.chosenMagSacLoc((-lag)+1:end);
                app.vars.time_aligned = app.vars.tmag((-lag)+1:end);
                app.vars.vid_aligned = app.vars.vidH_upsample(1:end+lag);
                app.vars.vidV_aligned = app.vars.vidV_upsample(1:end+lag);
                app.vars.vid_keep = ~app.vars.sacLoc_vid(1:end+lag);
            else
                app.vars.mag_aligned = app.vars.chosenMag(1:end-lag);
                app.vars.mag_keep = ~app.vars.chosenMagSacLoc(1:end-lag);
                app.vars.time_aligned = app.vars.tmag(1:end-lag);
                app.vars.vid_aligned = app.vars.vidH_upsample((lag)+1:end);
                app.vars.vidV_aligned = app.vars.vidV_upsample((lag)+1:end);
                app.vars.vid_keep = ~app.vars.sacLoc_vid((lag)+1:end);
            end
            
            app.vars.keep = (app.vars.mag_keep & app.vars.vid_keep);
            percent_deleted = 100*(1-sum(int64(app.vars.keep))/length(app.vars.keep))

        end

        function varargout = eyecam_APP2a(app, vid, vars, eyetrack, nframes)
        
            %% Defaults for missing inputs
            if ~exist('eyetrack','var')
                eyetrack = 0;
            end
            
            if ~exist('nframes','var')
                nframes = 180*30;
            end
            
            %%  Prep
            start(vid(1));
            start(vid(2));
            AAA = figure(3);
            %fhandle = figure(1);
            i = 0;
            
            %% Imgage Color properties
            cMap = gray(256); % Most of the image is grayscale
            cMap(1,:) = [0 0 1]; % Last row is blue.
            cMap(256,:) = [1 0 0]; % Last row is red.
            
            %% Add a figure to begin recording for EyeTrack
            if eyetrack
                screensize = get(0,'Screensize');
                [screensize(3)/2+9  49 screensize(3)/2-16 screensize(4)-132]
                set(gcf, 'Position', [screensize(3)/2+9  49 screensize(3)/2-16 screensize(4)-132]); %
                msgh = msgbox('Press OK to start calibration');
                set(msgh, 'Position', [screensize(3:4)-[630 425] 120 50])
                
                trigger(vid)
                img1 = getdata(vid(1),1)';
                img2 = getdata(vid(2),1)';
                % because of the older logitech camera set up in D253, the images need to be rotates. This is not needed for the ELP cameras
                %img1_all = uint8(zeros(size(img1,1),size(img1,2),nframes)); %D253
                %img2_all = uint8(zeros(size(img1,1),size(img1,2),nframes)); %D253
                img1_all = uint8(zeros(size(img1,2),size(img1,1),nframes));  %D019
                img2_all = uint8(zeros(size(img1,2),size(img1,1),nframes));  %D019
            else
                set(gcf, 'Position', get(0,'Screensize')); % Maximize figure.
            end
            
            
            %% Plot first frame
            
            [img1, ~] = getsnapshot(vid(1));
            [img2, ~] = getsnapshot(vid(2));
            
%             subplot(1,2,1)
%             himg1 = imshow(img1); hold on;
%             colormap(cMap);            
%             plot(size(img1,2)/2, size(img1,1)/2,'+r', 'MarkerSize',1000);
%             subplot(1,2,2)
%             himg2 = imshow(img2); hold on;
%             colormap(cMap);
%             plot(size(img1,2)/2, size(img1,1)/2,'+r', 'MarkerSize',1000);
            
            himg1 = imshow(img1, 'Parent', app.UIAxes);
            colormap(app.UIAxes, cMap);
            plot(app.UIAxes, size(img1,2)/2, size(img1,1)/2,'+r', 'MarkerSize',1000);
            hold(app.UIAxes, 'on')
            
            himg2 = imshow(img2, 'Parent', app.UIAxes_2);
            colormap(app.UIAxes_2, cMap);
            plot(app.UIAxes_2, size(img2,2)/2, size(img2,1)/2,'+r', 'MarkerSize',1000);
            hold(app.UIAxes_2, 'on')


            %% collect images until user closes  window
            tic
            while ~app.vars.videoSetupComplete
                
                % Quit if enough frames are captured
                if eyetrack && i>=nframes
                    close(gcf)
                    pause(.1)
                    break
                end
                
                if ~exist('msgh','var') ||  ~ishandle(msgh)
                    i = i + 1;
                    
                    [img1, ~] = getsnapshot(vid(1));
                    [img2, ~] = getsnapshot(vid(2));
                    
                    time3(i) = toc;
                    
                    if mod(i, 60) == 0
                        fps = 1/(mean(time3(end-58:end) - time3(end-59:end-1)));
                        hold on
                        AAA;
                        scatter(i, fps);
                        hold off
                    end
                    
                else
                    img1  = getdata(vid(1),1);
                    img2  = getdata(vid(2),1);
                end
                
                % hobin don't know why things were rotated but I left them unrotated
                % maybe unrotating them will give me an error later on
                img1 = rot90(img1,-1);
                img1 = rot90(img1,-1);
                img2 = rot90(img2,-1);
                img2 = rot90(img2,-1);
                
                % Store images in large 3D matrix only when doing eyetrack
                if eyetrack  && i > 0
                    %i % counter
                    img1_all(:,:,i) = img1;
                    img2_all(:,:,i) = img2;
                end
                
                % Update Image
                set(himg1, 'CData',img1);
                set(himg2, 'CData',img2);
            end
            
            stop(vid);
            
            %% Define outputs
            if exist('time1','var')
                meanFrameRate = mean(1./diff(time1))
                results.time1 = time1';
                results.time2 = time2';
                results.time3 = time3'; % double check
            end
            
            if nargout==1
                varargout = {results};
            elseif nargout==2
                varargout = {img1 img2};
            end
            
            %% Save images if running an expmt
            if eyetrack
                try
                    % Save the resulting timestamps
                    save('time', 'results');
                    
                    %% Save as tiff    - make sure windows explorer is closed!
                    tic
                    
                    dbstop if error
                    disp('Saving images')
                    
                    saveastiff(img1_all, fullfile(pwd,'img1.tiff'));
                    saveastiff(img2_all, fullfile(pwd,'img2.tiff'));
                    
                    dbclear if error
                    fprintf('\n%d frames saved in current folder\n',i)
                    t = toc;
                    fprintf('%f sec elapsed per 100 images.\n',t/i*100)
                    
                catch msgid
                    disp(msgid.message) % If you get an error here, run the cell above:
                    keyboard            % ("%% Save as tiff")
                end
            end
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            drawnow;
            app.UIFigure.WindowState = 'maximized';
        end

        % Button pushed function: SetupCamerasButton
        function SetupCamerasButtonPushed(app, event)
            % get parameters from gui
            res = [app.CameraResolutionEditField.Value app.CameraResolutionEditField_2.Value];
            boxSize = app.BoxSizeEditField.Value;
            boxOffset = app.BoxOffsetEditField.Value;
            
            % [x_offest, y_offset, width, length]
            roiInitial = [(res-boxSize)/2 boxSize boxSize]+[0 boxOffset 0 0];
            
            % Set the camera specific parameters. (frame rate, exposure, etc...)
            vid = setupvideo_new2019(roiInitial); pause(.1)
            
            % Show preview until figure closed
            % img1 and img2 are 'cubes' or 'stacks' of images (not from experiment)
            app.vars.videoSetupComplete = 0;
            [img1, img2] = eyecam_APP2a(app, vid, app.vars); % ***Run "eyecam_APP(vid);" to see preview at any point ***%
            %[img1, img2] = eyecam(app, vid); % ***Run "eyecam_APP(vid);" to see preview at any point ***%
            
            % Prompt for ROI around one eye - first click on camera image from the left
            % side, then click-drag to select rectangle
            vid = setROI(vid,img1, img2, res, boxSize, boxOffset);
            
        end

        % Button pushed function: CollectDataButton
        function CollectDataButtonPushed(app, event)
            % Prompt User for calibration folder location
            folder = uigetdir(cd, 'Select Calibration Folder');
            
            if exist(fullfile(folder,'img1.tiff'),'file')
                warning('Overwrite')
                delete(fullfile(pwd, 'img1.tiff'),fullfile(pwd,'img2.tiff'))
            end
            
            close all
            eyetrackon = 1;
            nframes = 30*120; % 30*nseconds
            eyecam_APP(app, vid,eyetrackon,nframes); % results = time stamps, images are saves as img1 and 2
            warning on
        end

        % Button pushed function: TestParametersButton
        function TestParametersButtonPushed(app, event)
            cla(app.UIAxes2)
            cla(app.UIAxes2_2)
            
            radiiPupil = app.RadiusPupilEditField.Value;
            xpad = app.XPaddingEditField.Value;
            ypad = app.YPaddingEditField.Value;
            radiiCR1 = [app.RadiusCornealReflection1EditField.Value app.radiiCR1EditField_2.Value];
            radiiCR2 = [app.RadiusCornealReflection2EditField.Value app.radiiCR2EditField_2.Value];
            minfeatures = app.MinimumFeaturesEditField.Value;
            imAdjust = app.ImproveImageContrastCheckBox.Value;
            manual = app.ManualPupilLocationSelectionCheckBox.Value;
            tic
            videoTest_APP(app, radiiPupil,[xpad ypad],radiiCR1,radiiCR2,minfeatures,'ImAdjust',imAdjust,'Manual',manual)
            toc
            close all
        end

        % Button pushed function: RunAnalysisButton
        function RunAnalysisButtonPushed(app, event)
            % Locate pupil and CR
            eyeAnalysis_APP(app)
            
            % Save results
            saveas(1,'eyetrack1.jpg');
            saveas(2,'eyetrack2.jpg');
        end

        % Button pushed function: findmagscaleVelButton
        function findmagscaleVelButtonPushed(app, event)
            findmagscaleVelAPP(app);       
        end

        % Button pushed function: PLOT_DesaccededTracesButton
        function PLOT_DesaccededTracesButtonPushed(app, event)
            %findmagscaleVelAPP(app);
            
            figure();
            desacPLot = tight_subplot(3,1,[.025 .025],[.025 .025],[.015 .01]);
 
            axes(desacPLot(1));
            title('Magnet 1 Desaccading, Unscaled'); hold on;
            plot(app.vars.tmag, app.vars.mag1Vel,'k'); 
            mag1VelPlot_desac = app.vars.mag1Vel;
            mag1VelPlot_desac(~app.vars.keep) = NaN;
            plot(app.vars.tmag, mag1VelPlot_desac,'b', 'LineWidth', .5); 
            plot(app.vars.tmag,app.vars.vars*app.vars.bMag1,'r-', 'LineWidth', 1);
            ylim([-10 10]);
            text(1, 1.6, sprintf('Amp = %.3f\nr^2 = %.3f\nScale Factor = %.3f',app.vars.mag1Amp, app.vars.r2mag1, app.vars.scaleCh1),'BackgroundColor','w')
            yticklabels(yticks)
            
            axes(desacPLot(2));
            title('Magnet 2 Desaccading, Unscaled'); hold on;
            plot(app.vars.tmag, app.vars.mag2Vel,'k');  
            mag2VelPlot_desac = app.vars.mag2Vel;
            mag2VelPlot_desac(~app.vars.keep) = NaN;
            plot(app.vars.tmag, mag2VelPlot_desac,'b', 'LineWidth', .5);  
            plot(app.vars.tmag,app.vars.vars*app.vars.bMag2,'r-', 'LineWidth', 1);
            ylim([-10 10]);
            text(1, 1.6, sprintf('Amp = %.3f\nr^2 = %.3f\nScale Factor = %.3f',app.vars.mag2Amp, app.vars.r2mag2, app.vars.scaleCh2),'BackgroundColor','w')
            yticklabels(yticks)
            
            axes(desacPLot(3));
            title('Video Desaccading'); hold on;
            plot(app.vars.tmag, app.vars.vidVel,'k');  
            vidVelplot_desac = app.vars.vidVel;
            vidVelplot_desac(~app.vars.keep) = NaN;
            plot(app.vars.tmag, vidVelplot_desac,'b', 'LineWidth', .5);  
            plot(app.vars.tmag, app.vars.vars*app.vars.bVid,'r-', 'LineWidth', 1);
            %ylim([-25 25]);
            text(1, 20, sprintf('Amp = %.3f\nr^2 = %.3f',app.vars.vidAmp, app.vars.r2vid),'BackgroundColor','w')
            yticklabels(yticks)
            
            % Cosmetics
            xlabel('s')
            linkaxes(desacPLot, 'x')
            xticks(0:5:max(xticks))
            xticklabels(0:5:max(xticks))
            %xlim([0, 40])
            box off
            
            % Save Figures
            savefig('Calib_Desaccading_Summary.fig')
        end

        % Button pushed function: PLOT_FramerateHistogramButton
        function PLOT_FramerateHistogramButtonPushed(app, event)
            %findmagscaleVelAPP(app);
            
            difft1 = diff(app.vars.vidResults.time1);
            difft2 = diff(app.vars.vidResults.time2);
            frametimes = cat(1, difft1, difft2);
            
            figure();
            histogram(frametimes, 100);
            title('Framerate Histogram (Both Cameras)')
            ylabel('Frame Amount')
            xlabel('framerate')
        end

        % Callback function
        function TimealignmentofMagnetandVideoButtonPushed(app, event)
            findmagscaleVelAPP(app)

        end

        % Button pushed function: PlotAlignedTracesButton
        function PlotAlignedTracesButtonPushed(app, event)
            figure();
            plot(app.vars.time_aligned, app.vars.mag_aligned)
            yyaxis right
            plot(app.vars.time_aligned, app.vars.vid_aligned)
            xlabel('Time')
            ylabel('Position')
            legend('Magnet', 'Video')
        end

        % Button pushed function: PlotLinearityTracesButton
        function PlotLinearityTracesButtonPushed(app, event)
            mag_aligned = app.vars.mag_aligned;
            vid_aligned = app.vars.vid_aligned;
            time_aligned = app.vars.time_aligned;
            vidV_aligned = app.vars.vidV_aligned;
            keep = app.vars.keep;
            
            c = linspace(1,10,length(mag_aligned));
            
            figure();
            scatter(time_aligned(keep), mag_aligned(keep), .01, '.', 'k')
            yyaxis right
            scatter(time_aligned(keep), vid_aligned(keep), .01, c(keep), '.')
            colormap hsv;
            xlabel('Time')
            legend('Magnet', 'Video')
            
            figure();
            scatter(mag_aligned(keep), vid_aligned(keep), .01, vidV_aligned(keep), '.');
            colormap hsv;
                        
            figure();
            scatter(mag_aligned(keep), vid_aligned(keep), 3, c(keep));
            colormap hsv;
        end

        % Button pushed function: 
        % PlotDownsampleShortScatterPlotsButton
        function PlotDownsampleShortScatterPlotsButtonPushed(app, event)
            mag_aligned = app.vars.mag_aligned;
            vid_aligned = app.vars.vid_aligned;
            time_aligned = app.vars.time_aligned;
            vidV_aligned = app.vars.vidV_aligned;
            keep = app.vars.keep;
            tvid = app.vars.tvid;
            
            mag_down = interp1(time_aligned, mag_aligned, tvid);
            vid_down = interp1(time_aligned, vid_aligned, tvid);
            vidV_down = interp1(time_aligned, vidV_aligned, tvid);
            keep_down = interp1(time_aligned, double(keep), tvid);
            keep_down(isnan(keep_down)) = 0;
            keep_down = logical(keep_down);
            
           
            c = linspace(1,10,length(mag_down));
            
            figure();
            scatter(mag_down(keep_down), vid_down(keep_down), [], c(keep_down));
            colormap hsv;
            title('Scatterplot after downsampling magnet data')
            xlabel('magnet')
            ylabel('video')
     
            figure();
            scatter(mag_down(keep_down), vid_down(keep_down), [], vidV_down(keep_down));
            colormap hsv;
            title('Scatterplot after downsampling magnet data')
            xlabel('magnet')
            ylabel('video')
        end

        % Button pushed function: LinearRegressiononAllDataButton
        function LinearRegressiononAllDataButtonPushed(app, event)
            mag_aligned = app.vars.mag_aligned;
            vid_aligned = app.vars.vid_aligned;
            time_aligned = app.vars.time_aligned;
            vidV_aligned = app.vars.vidV_aligned;
            keep = app.vars.keep;
            tvid = app.vars.tvid;
            
            mag_down = interp1(time_aligned, mag_aligned, tvid);
            vid_down = interp1(time_aligned, vid_aligned, tvid);
            vidV_down = interp1(time_aligned, vidV_aligned, tvid);
            keep_down = interp1(time_aligned, double(keep), tvid);
            keep_down(isnan(keep_down)) = 0;
            keep_down = logical(keep_down);
            
            
            x = mag_down(keep_down);
            y = vid_down(keep_down);
            
            fit = polyfit(x,y,1);
            range = linspace(min(x),max(x),100);
            yfit = fit(1)*range + fit(2);
            
            figure();
            scatter(x,y);hold on            
            plot(range, yfit, 'k', 'lineWidth', 2);
            R = corrcoef(x,y);
            Rsq = R(1,2).^2
        end

        % Button pushed function: 
        % LienarRegressiononEachSegmentButton
        function LienarRegressiononEachSegmentButtonPushed(app, event)

            weighted_sum = 0;
            
            for i = 1:length(app.vars.keep_start)
                fit = polyfit(app.vars.mag_aligned(app.vars.keep_start(i):app.vars.keep_end(i)),app.vars.vid_aligned(app.vars.keep_start(i):app.vars.keep_end(i)),1);
                m(i) = fit(1);
                weighted_sum = weighted_sum + (app.vars.keep_end(i) - app.vars.keep_start(i))*fit(1);
            end
            
                figure(); 
                hist(m);
            
            segment_stats = [length(app.vars.keep_start), mean(m), std(m), var(m)]
            weighted_avg = weighted_sum/sum(int64(app.vars.keep))

        end

        % Button pushed function: DoneSetupButton
        function DoneSetupButtonPushed(app, event)
            app.vars.videoSetupComplete = 1;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.VideoCollectionTab = uitab(app.TabGroup);
            app.VideoCollectionTab.Title = 'Video Collection';
            app.CameraParametersLabel = uilabel(app.VideoCollectionTab);
            app.CameraParametersLabel.Text = 'Camera Parameters';
            app.PresetsDropDownLabel = uilabel(app.VideoCollectionTab);
            app.PresetsDropDownLabel.Text = 'Presets';
            app.PresetsDropDown = uidropdown(app.VideoCollectionTab);
            app.PresetsDropDown.Items = {'ELP', 'Custom'};
            app.PresetsDropDown.Value = 'ELP';
            app.CameraResolutionEditFieldLabel = uilabel(app.VideoCollectionTab);
            app.CameraResolutionEditFieldLabel.Text = 'Camera Resolution';
            app.CameraResolutionEditField = uieditfield(app.VideoCollectionTab, 'numeric');
            app.CameraResolutionEditField.Tooltip = {'Camera Resolution'};
            app.CameraResolutionEditField.Value = 1280;
            app.BoxSizeEditFieldLabel = uilabel(app.VideoCollectionTab);
            app.BoxSizeEditFieldLabel.Text = 'Box Size';
            app.BoxSizeEditField = uieditfield(app.VideoCollectionTab, 'numeric');
            app.BoxSizeEditField.Tooltip = {'Length and Width of the square during initial view. (a crop of the resolution)'};
            app.BoxSizeEditField.Value = 500;
            app.BoxOffsetEditFieldLabel = uilabel(app.VideoCollectionTab);
            app.BoxOffsetEditFieldLabel.Text = 'Box Offset';
            app.BoxOffsetEditField = uieditfield(app.VideoCollectionTab, 'numeric');
            app.BoxOffsetEditField.Tooltip = {'Vertical Offset (pixels) below the line at (res(2)-box)/2 pixels above the bottom of the camera''s full field of view (indicated by'; 'res)'};
            app.CameraResolutionEditField_2 = uieditfield(app.VideoCollectionTab, 'numeric');
            app.CameraResolutionEditField_2.Value = 1024;
            app.UIAxes = uiaxes(app.VideoCollectionTab);
            title(app.UIAxes, 'Camera 1')
            xlabel(app.UIAxes, '')
            ylabel(app.UIAxes, '')
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.UIAxes_2 = uiaxes(app.VideoCollectionTab);
            title(app.UIAxes_2, 'Camera 1')
            xlabel(app.UIAxes_2, '')
            ylabel(app.UIAxes_2, '')
            app.UIAxes_2.XTick = [];
            app.UIAxes_2.YTick = [];
            app.SetupCamerasButton = uibutton(app.VideoCollectionTab, 'push');
            app.SetupCamerasButton.ButtonPushedFcn = createCallbackFcn(app, @SetupCamerasButtonPushed, true);
            app.SetupCamerasButton.Text = 'Setup Cameras';
            app.CollectDataButton = uibutton(app.VideoCollectionTab, 'push');
            app.CollectDataButton.ButtonPushedFcn = createCallbackFcn(app, @CollectDataButtonPushed, true);
            app.CollectDataButton.Text = 'Collect Data';
            app.NumberofframestocollectEditFieldLabel = uilabel(app.VideoCollectionTab);
            app.NumberofframestocollectEditFieldLabel.Text = 'Number of frames to collect';
            app.NumberofframestocollectEditField = uieditfield(app.VideoCollectionTab, 'numeric');
            app.NumberofframestocollectEditField.Tooltip = {'@ 30fps for 120 seconds'};
            app.NumberofframestocollectEditField.Value = 3600;
            app.DoneSetupButton = uibutton(app.VideoCollectionTab, 'push');
            app.DoneSetupButton.ButtonPushedFcn = createCallbackFcn(app, @DoneSetupButtonPushed, true);
            app.DoneSetupButton.Text = 'Done Setup';
            app.OfflineEyeTrackingTab = uitab(app.TabGroup);
            app.OfflineEyeTrackingTab.Title = 'Offline Eye Tracking';
            app.RadiusPupilEditFieldLabel = uilabel(app.OfflineEyeTrackingTab);
            app.RadiusPupilEditFieldLabel.Text = 'Radius: Pupil';
            app.RadiusPupilEditField = uieditfield(app.OfflineEyeTrackingTab, 'numeric');
            app.RadiusPupilEditField.Tooltip = {'radius in pixels'};
            app.RadiusPupilEditField.Value = 50;
            app.XPaddingEditFieldLabel = uilabel(app.OfflineEyeTrackingTab);
            app.XPaddingEditFieldLabel.Text = 'X - Padding';
            app.XPaddingEditField = uieditfield(app.OfflineEyeTrackingTab, 'numeric');
            app.XPaddingEditField.Tooltip = {'X Padding around Pupil in pixels'};
            app.XPaddingEditField.Value = 100;
            app.YPaddingEditFieldLabel = uilabel(app.OfflineEyeTrackingTab);
            app.YPaddingEditFieldLabel.Text = 'Y - Padding';
            app.YPaddingEditField = uieditfield(app.OfflineEyeTrackingTab, 'numeric');
            app.YPaddingEditField.Tooltip = {'Y Padding around Pupil in pixels'};
            app.YPaddingEditField.Value = 40;
            app.RadiusCornealReflection1EditFieldLabel = uilabel(app.OfflineEyeTrackingTab);
            app.RadiusCornealReflection1EditFieldLabel.Text = 'Radius: Corneal Reflection 1 ';
            app.RadiusCornealReflection1EditField = uieditfield(app.OfflineEyeTrackingTab, 'numeric');
            app.RadiusCornealReflection1EditField.Value = 6;
            app.RadiusCornealReflection2Label = uilabel(app.OfflineEyeTrackingTab);
            app.RadiusCornealReflection2Label.Text = 'Radius: Corneal Reflection 2';
            app.RadiusCornealReflection2EditField = uieditfield(app.OfflineEyeTrackingTab, 'numeric');
            app.RadiusCornealReflection2EditField.Value = 6;
            app.MinimumFeaturesEditFieldLabel = uilabel(app.OfflineEyeTrackingTab);
            app.MinimumFeaturesEditFieldLabel.Text = 'Minimum Features';
            app.MinimumFeaturesEditField = uieditfield(app.OfflineEyeTrackingTab, 'numeric');
            app.MinimumFeaturesEditField.Tooltip = {'Fraction of features needed for pupil detection'};
            app.MinimumFeaturesEditField.Value = 0.5;
            app.radiiCR1EditField_2 = uieditfield(app.OfflineEyeTrackingTab, 'numeric');
            app.radiiCR1EditField_2.Value = 12;
            app.radiiCR2EditField_2 = uieditfield(app.OfflineEyeTrackingTab, 'numeric');
            app.radiiCR2EditField_2.Value = 12;
            app.ManualPupilLocationSelectionCheckBox = uicheckbox(app.OfflineEyeTrackingTab);
            app.ManualPupilLocationSelectionCheckBox.Text = 'Manual Pupil Location Selection';
            app.ManualPupilLocationSelectionCheckBox.Value = true;
            app.ImproveImageContrastCheckBox = uicheckbox(app.OfflineEyeTrackingTab);
            app.ImproveImageContrastCheckBox.Text = 'Improve Image Contrast';
            app.ImproveImageContrastCheckBox.Value = true;
            app.TestParametersButton = uibutton(app.OfflineEyeTrackingTab, 'push');
            app.TestParametersButton.ButtonPushedFcn = createCallbackFcn(app, @TestParametersButtonPushed, true);
            app.TestParametersButton.Text = 'Test Parameters';
            app.RunAnalysisButton = uibutton(app.OfflineEyeTrackingTab, 'push');
            app.RunAnalysisButton.ButtonPushedFcn = createCallbackFcn(app, @RunAnalysisButtonPushed, true);
            app.RunAnalysisButton.Text = 'Run Analysis';
            app.edgeThreshCam1EditFieldLabel = uilabel(app.OfflineEyeTrackingTab);
            app.edgeThreshCam1EditFieldLabel.Text = 'edgeThreshCam1';
            app.edgeThreshCam1EditField = uieditfield(app.OfflineEyeTrackingTab, 'numeric');
            app.edgeThreshCam1EditField.Tooltip = {'Initial gradient threshold of pupil edge detection for cam 1'};
            app.edgeThreshCam1EditField.Value = 35;
            app.edgeThreshCam2EditFieldLabel = uilabel(app.OfflineEyeTrackingTab);
            app.edgeThreshCam2EditFieldLabel.Text = 'edgeThreshCam2';
            app.edgeThreshCam2EditField = uieditfield(app.OfflineEyeTrackingTab, 'numeric');
            app.edgeThreshCam2EditField.Tooltip = {'Initial gradient threshold of pupil edge detection for cam 2'; ''};
            app.edgeThreshCam2EditField.Value = 35;
            app.PlotAnalysisCheckBox = uicheckbox(app.OfflineEyeTrackingTab);
            app.PlotAnalysisCheckBox.Text = 'Plot Analysis';
            app.PlotAnalysisCheckBox.Value = true;
            app.debugCheckBox = uicheckbox(app.OfflineEyeTrackingTab);
            app.debugCheckBox.Text = 'debug';
            app.UIAxes2 = uiaxes(app.OfflineEyeTrackingTab);
            title(app.UIAxes2, '')
            xlabel(app.UIAxes2, '')
            ylabel(app.UIAxes2, '')
            app.UIAxes2.XTick = [];
            app.UIAxes2.YTick = [];
            app.UIAxes2_2 = uiaxes(app.OfflineEyeTrackingTab);
            title(app.UIAxes2_2, '')
            xlabel(app.UIAxes2_2, '')
            ylabel(app.UIAxes2_2, '')
            app.UIAxes2_2.XTick = [];
            app.UIAxes2_2.YTick = [];
            app.UIAxes3 = uiaxes(app.OfflineEyeTrackingTab);
            title(app.UIAxes3, '')
            xlabel(app.UIAxes3, '')
            ylabel(app.UIAxes3, '')
            app.UIAxes3.XTick = [];
            app.UIAxes3.YTick = [];
            app.UIAxes3_2 = uiaxes(app.OfflineEyeTrackingTab);
            title(app.UIAxes3_2, '')
            xlabel(app.UIAxes3_2, '')
            ylabel(app.UIAxes3_2, '')
            app.UIAxes3_2.XTick = [];
            app.UIAxes3_2.YTick = [];
            app.EyeAnalysisTab = uitab(app.TabGroup);
            app.EyeAnalysisTab.Title = 'Eye Analysis';
            app.CalibrationStimFrequencyEditFieldLabel = uilabel(app.EyeAnalysisTab);
            app.CalibrationStimFrequencyEditFieldLabel.Text = 'Calibration Stim Frequency';
            app.CalibrationStimFrequencyEditField = uieditfield(app.EyeAnalysisTab, 'numeric');
            app.CalibrationStimFrequencyEditField.Value = 1;
            app.LeftEyeCheckBox = uicheckbox(app.EyeAnalysisTab);
            app.LeftEyeCheckBox.Tooltip = {'If unchecked'; ' use right eye'};
            app.LeftEyeCheckBox.Text = 'Left Eye';
            app.LeftEyeCheckBox.Value = true;
            app.Camera2TimestampsCheckBox = uicheckbox(app.EyeAnalysisTab);
            app.Camera2TimestampsCheckBox.Tooltip = {'If unchecked'; ' use camera 1'};
            app.Camera2TimestampsCheckBox.Text = 'Camera 2 Timestamps';
            app.Camera2TimestampsCheckBox.Value = true;
            app.Step1AnalyzeDataPanel = uipanel(app.EyeAnalysisTab);
            app.Step1AnalyzeDataPanel.Title = 'Step 1: Analyze Data';
            app.findmagscaleVelButton = uibutton(app.Step1AnalyzeDataPanel, 'push');
            app.findmagscaleVelButton.ButtonPushedFcn = createCallbackFcn(app, @findmagscaleVelButtonPushed, true);
            app.findmagscaleVelButton.Text = 'findmagscaleVel';
            app.SaccadeWindowEditField_2 = uieditfield(app.Step1AnalyzeDataPanel, 'numeric');
            app.SaccadeWindowEditField_2.Value = 50;
            app.SaccadeThresholdVideoEditFieldLabel = uilabel(app.Step1AnalyzeDataPanel);
            app.SaccadeThresholdVideoEditFieldLabel.Text = 'Saccade Threshold Video';
            app.SaccadeThresholdVideoEditField = uieditfield(app.Step1AnalyzeDataPanel, 'numeric');
            app.SaccadeThresholdVideoEditField.Value = 500;
            app.SaccadeWindowmsEditFieldLabel = uilabel(app.Step1AnalyzeDataPanel);
            app.SaccadeWindowmsEditFieldLabel.Text = 'Saccade Window (ms)';
            app.SaccadeWindowmsEditField = uieditfield(app.Step1AnalyzeDataPanel, 'numeric');
            app.SaccadeWindowmsEditField.Value = 50;
            app.SaccadeThresholdMagnetEditFieldLabel = uilabel(app.Step1AnalyzeDataPanel);
            app.SaccadeThresholdMagnetEditFieldLabel.Text = 'Saccade Threshold Magnet';
            app.SaccadeThresholdMagnetEditField = uieditfield(app.Step1AnalyzeDataPanel, 'numeric');
            app.SaccadeThresholdMagnetEditField.Value = 0.1;
            app.MinimumGoodDataLengthEditFieldLabel = uilabel(app.Step1AnalyzeDataPanel);
            app.MinimumGoodDataLengthEditFieldLabel.Text = 'Minimum Good Data Length';
            app.MinimumGoodDataLengthEditField = uieditfield(app.Step1AnalyzeDataPanel, 'numeric');
            app.MinimumGoodDataLengthEditField.Value = 1;
            app.AlignBestSegmentCheckBox = uicheckbox(app.Step1AnalyzeDataPanel);
            app.AlignBestSegmentCheckBox.Tooltip = {'If unchecked'; ' algorithm attampts to align entire trace'};
            app.AlignBestSegmentCheckBox.Text = 'Align Best Segment';
            app.AlignBestSegmentCheckBox.Value = true;
            app.Step2PlotDataPanel = uipanel(app.EyeAnalysisTab);
            app.Step2PlotDataPanel.Title = 'Step 2: Plot Data';
            app.PLOT_DesaccededTracesButton = uibutton(app.Step2PlotDataPanel, 'push');
            app.PLOT_DesaccededTracesButton.ButtonPushedFcn = createCallbackFcn(app, @PLOT_DesaccededTracesButtonPushed, true);
            app.PLOT_DesaccededTracesButton.Text = 'Plot Desacceded Traces';
            app.PLOT_FramerateHistogramButton = uibutton(app.Step2PlotDataPanel, 'push');
            app.PLOT_FramerateHistogramButton.ButtonPushedFcn = createCallbackFcn(app, @PLOT_FramerateHistogramButtonPushed, true);
            app.PLOT_FramerateHistogramButton.Text = 'Plot Framerate Histogram';
            app.PlotAlignedTracesButton = uibutton(app.Step2PlotDataPanel, 'push');
            app.PlotAlignedTracesButton.ButtonPushedFcn = createCallbackFcn(app, @PlotAlignedTracesButtonPushed, true);
            app.PlotAlignedTracesButton.Text = 'Plot Aligned Traces';
            app.PlotLinearityTracesButton = uibutton(app.Step2PlotDataPanel, 'push');
            app.PlotLinearityTracesButton.ButtonPushedFcn = createCallbackFcn(app, @PlotLinearityTracesButtonPushed, true);
            app.PlotLinearityTracesButton.Text = 'Plot Linearity Traces';
            app.PlotDownsampleShortScatterPlotsButton = uibutton(app.Step2PlotDataPanel, 'push');
            app.PlotDownsampleShortScatterPlotsButton.ButtonPushedFcn = createCallbackFcn(app, @PlotDownsampleShortScatterPlotsButtonPushed, true);
            app.PlotDownsampleShortScatterPlotsButton.Text = 'Plot Downsample & Short Scatter Plots';
            app.LinearRegressiononAllDataButton = uibutton(app.Step2PlotDataPanel, 'push');
            app.LinearRegressiononAllDataButton.ButtonPushedFcn = createCallbackFcn(app, @LinearRegressiononAllDataButtonPushed, true);
            app.LinearRegressiononAllDataButton.Text = 'Linear Regression on All Data';
            app.LienarRegressiononEachSegmentButton = uibutton(app.Step2PlotDataPanel, 'push');
            app.LienarRegressiononEachSegmentButton.ButtonPushedFcn = createCallbackFcn(app, @LienarRegressiononEachSegmentButtonPushed, true);
            app.LienarRegressiononEachSegmentButton.Text = 'Lienar Regression on Each Segment';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = eyeCalibration

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end