classdef dataViz < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        UIAxes                     matlab.ui.control.UIAxes
        SliderLabel                matlab.ui.control.Label
        Slider                     matlab.ui.control.Slider
        StimShapeButtonGroup       matlab.ui.container.ButtonGroup
        SineButton                 matlab.ui.control.RadioButton
        StepButton                 matlab.ui.control.RadioButton
        FrequencyDropDownLabel     matlab.ui.control.Label
        FrequencyDropDown          matlab.ui.control.DropDown
        StimTypeDropDownLabel      matlab.ui.control.Label
        StimTypeDropDown           matlab.ui.control.DropDown
        ListBox                    matlab.ui.control.ListBox
        CyclesAfterSpinnerLabel    matlab.ui.control.Label
        CyclesAfterSpinner         matlab.ui.control.Spinner
        GenerateTimelapseButton    matlab.ui.control.Button
        AmplitudeDropDownLabel     matlab.ui.control.Label
        AmplitudeDropDown          matlab.ui.control.DropDown
        CleanThreshEditFieldLabel  matlab.ui.control.Label
        CleanThreshEditField       matlab.ui.control.NumericEditField
        PreEditFieldLabel          matlab.ui.control.Label
        PreEditField               matlab.ui.control.NumericEditField
        PostEditFieldLabel         matlab.ui.control.Label
        PostEditField              matlab.ui.control.NumericEditField
    end

    
    properties (Access = public)
        dataTable; % Description
        dataDirectory;
        excelLoc;
        
    end
    
    methods (Access = public)
        
        function UpdateFilterBox(app)
            
            tempTable = app.dataTable;
            
            % Amplitude Filter
            if isa(app.AmplitudeDropDown.Value, 'double')
                tempTable(tempTable.Amplitude ~= str2double(app.AmplitudeDropDown.Value), :) = [];
            else
                tempTable(~strcmp(tempTable.Amplitude, app.AmplitudeDropDown.Value), :) = [];
            end
            
            % StimType Filter 
            if isa(app.AmplitudeDropDown.Value, 'double')
                tempTable(tempTable.StimType ~= str2double(app.StimTypeDropDown.Value), :) = [];
            else
                tempTable(~strcmp(tempTable.StimType, app.StimTypeDropDown.Value), :) = [];
            end
            
            % Freq_Duration Filter
            if isa(app.FrequencyDropDown.Value, 'double')
                tempTable(tempTable.Freq_Duration ~= str2double(app.FrequencyDropDown.Value), :) = [];
            else
                tempTable(~strcmp(strsplit(num2str(tempTable.Freq_Duration')), app.FrequencyDropDown.Value), :) = [];
            end

            % Put Cell Names into box
            app.ListBox.Items = tempTable.Filename;
        end
    
        function plotThings(app)
            cla(app.UIAxes);
            hold(app.UIAxes);
            
            for i = 1:length(app.ListBox.Value)
                
                % Get Excel Table Information
                fileName = app.ListBox.Value{i};
                tableRow = app.dataTable(strcmp(app.dataTable.Filename, fileName), :);
                
                % Find behavior
                bfileData = dir([app.dataDirectory '\**\*' fileName]);
                bPath = fullfile(bfileData.folder, bfileData.name);
                chanDat = cx2dat(bPath, 6, 500);
                
                % Find Ephys  
                if ~strcmp(tableRow.EphysFilename, 'None')
                    eSamplerate = 50000;
                    
                    efileData = dir([app.dataDirectory '\**\*' tableRow.EphysFilename{:}]);
                    ePath = fullfile(efileData.folder, efileData.name);
                    ephysDat = maestro2dat(ePath, eSamplerate);
                    
                    % Are you missing an alignment value?
                    if tableRow.EphysAlignmentValue == 0 || isnan(tableRow.EphysAlignmentValue)
                        [chanDat, shiftAmt, ~] = alignCXandMaestro(chanDat, ephysDat, 1);
                        app.dataTable(strcmp(app.dataTable.Filename, fileName), :).EphysAlignmentValue = shiftAmt;
                        writetable(app.dataTable, app.excelLoc);
                    else
                        timeEphys = 0:1/eSamplerate:(length(ephysDat.data)-1)/eSamplerate;
                        timeEphys = timeEphys + tableRow.EphysAlignmentValue;
                        ephysDat.tstart = timeEphys(1);
                        ephysDat.tend = timeEphys(end);
                        chanDat(end+1) = ephysDat;
                    end
                    
                end
                
                
                %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                % Align all cycles to the same phase %
                
                % No constant term bc sine centered at 0
                warning('off','stats:regress:NoConst')
                
                segLength = length(chanDat(5).data);
                segTime = (1:segLength)/chanDat(5).samplerate; 
                freq = str2double(app.FrequencyDropDown.Value);
                y1 = sin(2*pi*freq*segTime(:));
                y2 = cos(2*pi*freq*segTime(:));
                constant = ones(segLength,1);
                vars = [y1 y2 constant];
    
                % headVel
                b = regress(chanDat(5).data, vars);
                headVel_amp = sqrt(b(1)^2+b(2)^2);
                headVel_angle = rad2deg(atan2(b(2), b(1)))
                
                % drumVel
                b = regress(chanDat(7).data, vars);
                drumVel_amp = sqrt(b(1)^2+b(2)^2);
                drumVel_angle = rad2deg(atan2(b(2), b(1)));
                
                % eyeVel (desaccaded)
                %[b,~,~,~,stat] = regress(eyeVel(~omitH), vars(~omitH,:));
                %eyeVel_amp = sqrt(b(1)^2+b(2)^2);
                %eyeVel_phase = rad2deg(atan2(b(2), b(1)));
                
                % calculate eye gain as VOR or OKR based on head signal
                % Chair/VOR Stimulus
                if contains(app.StimTypeDropDown.Value, "OKR")
                    reference_angle = drumVel_angle;
                else
                    reference_angle = headVel_angle
                end

                startpt = max(1,round(mod(-reference_angle,360)/360 * chanDat(5).samplerate/freq));
                startptEphys = max(1,round(mod(-reference_angle,360)/360 * eSamplerate/freq));
                
                %startpt = 1;
                %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                %% Plot
                
                % Plot hhvel -1
                cycleLength = chanDat(5).samplerate / str2double(app.FrequencyDropDown.Value);
                timeVec = 0:( 1/chanDat(5).samplerate ):(cycleLength-1)/chanDat(5).samplerate;
                [hhvelMat, hhvelMean] = VOR_breakTrace(cycleLength, startpt, chanDat(5).data);
                plot(app.UIAxes, timeVec, hhvelMean', 'k'); 
                
                % Plot htvel = 7
                cycleLength = chanDat(7).samplerate / str2double(app.FrequencyDropDown.Value);
                timeVec = 0:( 1/chanDat(7).samplerate ):(cycleLength-1)/chanDat(7).samplerate;
                [htvelMat, htvelMean] = VOR_breakTrace(cycleLength, startpt, chanDat(7).data);
                plot(app.UIAxes, timeVec, htvelMean', 'b');
                
                % Plot hevel - 3
                cleanThresh = app.CleanThreshEditField.Value;
                presaccade = app.PreEditField.Value;
                postsaccade = app.PostEditField.Value;
                
                % Plot Spike Density Function
                spikeTimes = chanDat(8).data;
                sdf = plotSpikeDensityfunction(spikeTimes, .05);
                cycleLength = eSamplerate / str2double(app.FrequencyDropDown.Value);
                timeVec = 0:( 1/eSamplerate ):(cycleLength-1)/eSamplerate;
                [sdfMat, sdfMean] = VOR_breakTrace(cycleLength, startptEphys, sdf);
                try
                    plot(app.UIAxes, timeVec, sdfMean', 'm');
                catch
                end
                
                [omitH, omitCenters, eye_vel_filt] = desaccadeVel_B(chanDat(3).data, chanDat(3).samplerate, str2double(app.FrequencyDropDown.Value), presaccade, postsaccade, cleanThresh);
                plotVel = chanDat(3).data;
                plotVel(omitH) = NaN;
                cycleLength = chanDat(3).samplerate / str2double(app.FrequencyDropDown.Value);
                timeVec = 0:( 1/chanDat(3).samplerate ):(cycleLength-1)/chanDat(3).samplerate;
                [hevelMat, hevelMean] = VOR_breakTrace(cycleLength, startpt, plotVel);
                %plot(app.UIAxes, timeVec, hevelMat', 'r');
                
                %[hevelMat, hevelMean] = VOR_breakTrace(cycleLength, 1, chanDat(3).data);
                %plot(app.UIAxes, timeVec, hevelMat', 'm');
%                 figure()
%                 plot(chanDat(3).data)
%                 hold on
%                 plot(plotVel, 'k')
                
                y1 = sin(2*pi*freq*segTime(:));
                y2 = cos(2*pi*freq*segTime(:));
                constant = ones(segLength,1);
                vars = [y1 y2 constant];


%                 % Plot Ephys
%                 if ~strcmp(tableRow.EphysFilename, 'None')
%                     
%                     timeofsimplespikes = datchandata(chanDat,'ss');
%                     timeVec = 0:( 1/eSamplerate):(length(datchandata(chanDat, 'Ephys'))-1)/eSamplerate;
%                     cycleLength = eSamplerate / str2double(app.FrequencyDropDown.Value);
%                     t_samp = 1/eSamplerate;
%                     t_samp = 1/eSamplerate;
%                     tt = [0:t_samp:timeVec(end)];
%                    
%                     ifr = instantfr(timeofsimplespikes, tt);
%                     [ifrMat, ifrMean] = VOR_breakTrace(cycleLength, 1, ifr);
%                     timeVec = 0:( 1/eSamplerate):(cycleLength-1)/eSamplerate;
%                     plot(app.UIAxes, timeVec, ifrMean'/10, 'c');
% 
%                 end
            end
            
            legend(app.UIAxes,{'Head Vel','Target Vel', 'Eye Vel','ss'})
            xlim(app.UIAxes, [0 max(timeVec)]);
            ylim(app.UIAxes, [-50 50]);
            hold(app.UIAxes);
            
        end
             
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Load Excel file database/table
            app.excelLoc = 'C:\Users\Public\RaymondLabCode\Projects\Max - CF Project\Monkey Metadata by Max.xlsx';
            app.dataTable = readtable(app.excelLoc);
            app.dataDirectory = 'G:\My Drive\Expmt Data\Max\Climbing Fiber Project\Initial Data for testing';
            
            % populate options windows with what exists in the excel file
            uniqueStimType = unique(app.dataTable.StimType);
            uniqueStimType = uniqueStimType(~cellfun('isempty',uniqueStimType));
            app.StimTypeDropDown.Items = uniqueStimType;
            
            uniqueAmplitudes = unique(app.dataTable.Amplitude);
            %uniqueAmplitudes(isnan(uniqueAmplitudes)) = []
            %uniqueAmplitudes = strsplit(num2str(uniqueAmplitudes'))';
            uniqueAmplitudes = uniqueAmplitudes(~cellfun('isempty', uniqueAmplitudes));
            app.AmplitudeDropDown.Items = uniqueAmplitudes;
            
            uniqueFrequency = unique(app.dataTable.Freq_Duration);
            uniqueFrequency(isnan(uniqueFrequency)) = [];
            %uniqueFrequency = uniqueFrequency(~cellfun('isempty', uniqueFrequency));
            uniqueFrequency = strsplit(num2str(uniqueFrequency'));
            app.FrequencyDropDown.Items = uniqueFrequency;
            
        end

        % Button pushed function: GenerateTimelapseButton
        function GenerateTimelapseButtonPushed(app, event)
            plotThings(app)
        end

        % Value changed function: StimTypeDropDown
        function StimTypeDropDownValueChanged(app, event)
            UpdateFilterBox(app)
        end

        % Value changed function: FrequencyDropDown
        function FrequencyDropDownValueChanged(app, event)
            UpdateFilterBox(app)
        end

        % Value changed function: AmplitudeDropDown
        function AmplitudeDropDownValueChanged(app, event)
            UpdateFilterBox(app)
        end

        % Selection changed function: StimShapeButtonGroup
        function StimShapeButtonGroupSelectionChanged(app, event)
            UpdateFilterBox(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.SliderLabel = uilabel(app.UIFigure);
            app.SliderLabel.Text = 'Slider';
            app.Slider = uislider(app.UIFigure);
            app.StimShapeButtonGroup = uibuttongroup(app.UIFigure);
            app.StimShapeButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @StimShapeButtonGroupSelectionChanged, true);
            app.StimShapeButtonGroup.Title = 'Stim Shape';
            app.SineButton = uiradiobutton(app.StimShapeButtonGroup);
            app.SineButton.Text = 'Sine';
            app.SineButton.Value = true;
            app.StepButton = uiradiobutton(app.StimShapeButtonGroup);
            app.StepButton.Text = 'Step';
            app.FrequencyDropDownLabel = uilabel(app.UIFigure);
            app.FrequencyDropDownLabel.Text = 'Frequency';
            app.FrequencyDropDown = uidropdown(app.UIFigure);
            app.FrequencyDropDown.Items = {'.6Hz', '1 Hz', '2 Hz'};
            app.FrequencyDropDown.ValueChangedFcn = createCallbackFcn(app, @FrequencyDropDownValueChanged, true);
            app.FrequencyDropDown.Value = '2 Hz';
            app.StimTypeDropDownLabel = uilabel(app.UIFigure);
            app.StimTypeDropDownLabel.Text = 'Stim Type';
            app.StimTypeDropDown = uidropdown(app.UIFigure);
            app.StimTypeDropDown.Items = {'Dark', 'x1', 'x0', 'x2', 'VORC', 'Pos Steps', 'H Pursuit', 'V Pursuit'};
            app.StimTypeDropDown.ValueChangedFcn = createCallbackFcn(app, @StimTypeDropDownValueChanged, true);
            app.StimTypeDropDown.Value = 'x0';
            app.ListBox = uilistbox(app.UIFigure);
            app.ListBox.Items = {'Pears', 'Apples', ''};
            app.ListBox.Multiselect = 'on';
            app.ListBox.Value = {'Pears'};
            app.CyclesAfterSpinnerLabel = uilabel(app.UIFigure);
            app.CyclesAfterSpinnerLabel.Text = 'Cycles After';
            app.CyclesAfterSpinner = uispinner(app.UIFigure);
            app.CyclesAfterSpinner.Value = 1;
            app.GenerateTimelapseButton = uibutton(app.UIFigure, 'push');
            app.GenerateTimelapseButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateTimelapseButtonPushed, true);
            app.GenerateTimelapseButton.Text = 'Generate Timelapse';
            app.AmplitudeDropDownLabel = uilabel(app.UIFigure);
            app.AmplitudeDropDownLabel.Text = 'Amplitude';
            app.AmplitudeDropDown = uidropdown(app.UIFigure);
            app.AmplitudeDropDown.Items = {'Any', '10', '15', '20', '25', '30'};
            app.AmplitudeDropDown.ValueChangedFcn = createCallbackFcn(app, @AmplitudeDropDownValueChanged, true);
            app.AmplitudeDropDown.Value = '10';
            app.CleanThreshEditFieldLabel = uilabel(app.UIFigure);
            app.CleanThreshEditFieldLabel.Text = 'Clean Thresh';
            app.CleanThreshEditField = uieditfield(app.UIFigure, 'numeric');
            app.CleanThreshEditField.Value = 300000;
            app.PreEditFieldLabel = uilabel(app.UIFigure);
            app.PreEditFieldLabel.Text = 'Pre';
            app.PreEditField = uieditfield(app.UIFigure, 'numeric');
            app.PreEditField.Value = 0.01;
            app.PostEditFieldLabel = uilabel(app.UIFigure);
            app.PostEditFieldLabel.Text = 'Post';
            app.PostEditField = uieditfield(app.UIFigure, 'numeric');
            app.PostEditField.Value = 0.02;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = dataViz

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end