classdef VOR_GUI_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1         matlab.ui.Figure
        uipanel1        matlab.ui.container.Panel
        popupmenu1      matlab.ui.control.DropDown
        text2           matlab.ui.control.Label
        pushbutton1     matlab.ui.control.Button
        uipanel2        matlab.ui.container.Panel
        text8           matlab.ui.control.Label
        text10          matlab.ui.control.Label
        edit2           matlab.ui.control.EditField
        edit3           matlab.ui.control.EditField
        edit4           matlab.ui.control.EditField
        checkbox8       matlab.ui.control.CheckBox
        text14          matlab.ui.control.Label
        edit7           matlab.ui.control.EditField
        uipanel3        matlab.ui.container.Panel
        pushbutton2     matlab.ui.control.Button
        edit1           matlab.ui.control.EditField
        text7           matlab.ui.control.Label
        uipanel5        matlab.ui.container.Panel
        checkbox5       matlab.ui.control.CheckBox
        checkbox9       matlab.ui.control.CheckBox
        checkbox11      matlab.ui.control.CheckBox
        uibuttongroup4  matlab.ui.container.ButtonGroup
        radiobutton4    matlab.ui.control.RadioButton
        radiobutton5    matlab.ui.control.RadioButton
        pushbutton3     matlab.ui.control.Button
        uipanel3_2      matlab.ui.container.Panel
        checkbox6       matlab.ui.control.CheckBox
        uibuttongroup5  matlab.ui.container.ButtonGroup
        radiobutton7    matlab.ui.control.RadioButton
        radiobutton8    matlab.ui.control.RadioButton
    end

    
    methods (Access = private)
        function checkFolderStructure(app)
            
                app.text7.Text = ' ';
                app.text7.FontColor = [0 0 0];
            
                % Check if Real Folder
                if ~isfolder(app.edit1.Value)
                    app.text7.Text = 'Invalid Folder';
                    app.text7.FontColor = [1 0 0];
                    return
                end
            
                % Search for non-calibration smr files
                smr_files = dir([char(app.edit1.Value) '\**\*.smr']);
                smr_files = smr_files(~contains({smr_files.name}, '_cali'));
            
                % How many files were found?
                app.text7.Text = ['Files Found: ' num2str(length(smr_files))];
                if isempty(smr_files)
                    app.text7.FontColor = [1 0 0];
                else
                    app.text7.FontColor = [0 .6 .25];
                end
        end
        
%         function edit5_CreateFcn(app, hObject, eventdata, handles)
%             % hObject    handle to edit5 (see GCBO)
%             % eventdata  reserved - to be defined in a future version of MATLAB
%             % handles    empty - handles not created until after all CreateFcns called
%             
%             % Hint: edit controls usually have a white background on Windows.
%             %       See ISPC and COMPUTER.
%             if ispc && isequal(get(hObject,'BackgroundColor'), get(0,'defaultUicontrolBackgroundColor'))
%                 set(hObject,'BackgroundColor','white');
%             end
%         end
%         
%         function edit6_CreateFcn(app, hObject, eventdata, handles)
%             % hObject    handle to edit6 (see GCBO)
%             % eventdata  reserved - to be defined in a future version of MATLAB
%             % handles    empty - handles not created until after all CreateFcns called
%             
%             % Hint: edit controls usually have a white background on Windows.
%             %       See ISPC and COMPUTER.
%             if ispc && isequal(get(hObject,'BackgroundColor'), get(0,'defaultUicontrolBackgroundColor'))
%                 set(hObject,'BackgroundColor','white');
%             end
%         end
        
    end
    

    methods (Access = private)

        % Code that executes after component creation
        function VOR_GUI_OpeningFcn(app)
%                 % This function has no output args, see OutputFcn.
%                 % hObject    handle to figure
%                 % eventdata  reserved - to be defined in a future version of MATLAB
%                 % handles    structure with handles and user data (see GUIDATA)
%                 % varargin   command line arguments to VOR_GUI (see VARARGIN)
%             
%                 % Choose default command line output for VOR_GUI
%                 app.output = hObject;
%             
%                 % Update handles structure
%                 guidata(hObject, handles);
        end

        % Value changed function: checkbox6
        function checkbox6_Callback(app, event)
            if get(hObject, 'Value')
                dbstop if error
            else
                dbclear if error
            end
        end

        % Value changed function: checkbox8
        function checkbox8_Callback(app, event)
                if hObject.Value
                    % pre
                    app.edit2.String = '.05';
                    % post
                    app.edit3.String = '.05';
                    % thresh
                    app.edit4.String = '.02';
                else
                    % pre
                    app.edit2.String = '.075';
                    % post
                    app.edit3.String = '.2';
                    % thresh
                    app.edit4.String = '.55';
                end
        end

        % Value changed function: edit1
        function edit1_Callback(app, event)
                checkFolderStructure(app);
        end

        % Button pushed function: pushbutton1
        function pushbutton1_Callback(app, event)
                % Project Information
                warning('on');
                params.analysis = app.popupmenu1.String{app.popupmenu1.Value};
                params.folder = app.edit1.String;
                params.smr_files = dir([params.folder '\**\*.smr']);
                params.smr_files = params.smr_files(~contains({params.smr_files.name}, '_cali'));
                params.count = length(params.smr_files);
                params.cleanPlot = app.radiobutton8.Value;
                params.cleanAnalysis = app.radiobutton5.Value;
            
                % Extras Analysis / plotting
                params.newSac = app.checkbox8.Value;
                params.NoiseAnalysis = app.checkbox9.Value;
                params.do_eyeAmp_summary = 1;
                params.do_eyeGain_summary = 1;
                params.do_sineAnalysis = 1; % TODO
                params.do_filter = 0;%app.checkbox7.Value;
                params.do_subplot1 = app.checkbox5.Value;
                params.do_subplot2 = app.checkbox11.Value;
                params.minGoodChunk_len = str2double(app.edit7.String);
            
                % Saccade Parameters
                params.saccadePre = str2double(app.edit2.String);
                params.saccadePost = str2double(app.edit3.String);
                params.saccadeThresh = str2double(app.edit4.String);
            
                % Filtering Parameters
                %params.BPFilterLow = str2double(app.edit5.String);
                %params.BPFilterHigh = str2double(app.edit6.String);
            
                % Meta information
                params.Run_Date = strrep(char(datetime('now')), ':', '-');
                params.Computer = getenv('computername');
                params.Person = getenv('username');
            
                % Let the user know when the analysis is running & finished
                app.text11.ForegroundColor = [.3, .75, .93];
                app.text11.String = 'Running...';
                pause(.1)
                clc
                VOR_Tests(params);
                app.text11.String = 'Finished!';
                app.text11.ForegroundColor = [0 .6 .25];
        end

        % Button pushed function: pushbutton2
        function pushbutton2_Callback(app, event)
            % If there is already a folder listed in the edit box, open the popup
            % window to that folder.
            try cd(app.edit1.Value)
            catch
                app.edit1.Value = 'C:/';
            end
            
            
            % Change string of edit box to the selected folder
            folder = string(uigetdir(app.edit1.Value, 'Choose Folder that Contains the Data'));
            app.edit1.Value = folder;
            defaultFolder = app.edit1.Value;
            checkFolderStructure(app);
        end

        % Button pushed function: pushbutton3
        function pushbutton3_Callback(app, event)
            % close all figures
            figHandles = findobj('Type', 'figure');
            close(figHandles);
            %close(figHandles(~contains({figHandles.Name}, 'VOR_GUI')))
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.figure1 = uifigure;
            app.figure1.Name = 'VOR_GUI';
            app.uipanel1 = uipanel(app.figure1);
            app.uipanel1.Title = 'Analysis/Project';
            app.popupmenu1 = uidropdown(app.uipanel1);
            app.popupmenu1.Items = {'Default (Sine Only)', 'Amin_GC_Steps', 'Amin_Gen', 'Sriram_Gen', 'Sriram_OKR', 'Dark Rearing', 'Dark Rearing + Generalization'};
            app.popupmenu1.Value = 'Default (Sine Only)';
            app.text2 = uilabel(app.figure1);
            app.text2.Tooltip = 'maxwellg@stanford.edu, April-18';
            app.text2.Text = {'VOR '; 'Analysis'};
            app.pushbutton1 = uibutton(app.figure1, 'push');
            app.pushbutton1.ButtonPushedFcn = createCallbackFcn(app, @pushbutton1_Callback, true);
            app.pushbutton1.Text = 'Go!';
            app.uipanel2 = uipanel(app.figure1);
            app.uipanel2.Title = 'Processing';
            app.text8 = uilabel(app.uipanel2);
            app.text8.Text = {'Window (s)'; ''};
            app.text10 = uilabel(app.uipanel2);
            app.text10.Text = 'Thresh';
            app.edit2 = uieditfield(app.uipanel2, 'text');
            app.edit2.Value = '.05';
            app.edit3 = uieditfield(app.uipanel2, 'text');
            app.edit3.Value = '.05';
            app.edit4 = uieditfield(app.uipanel2, 'text');
            app.edit4.Value = '1000';
            app.checkbox8 = uicheckbox(app.uipanel2);
            app.checkbox8.ValueChangedFcn = createCallbackFcn(app, @checkbox8_Callback, true);
            app.checkbox8.Text = 'Clean v3.0';
            app.checkbox8.Value = true;
            app.text14 = uilabel(app.uipanel2);
            app.text14.Text = 'Min Data Len';
            app.edit7 = uieditfield(app.uipanel2, 'text');
            app.edit7.Value = '1';
            app.uipanel3 = uipanel(app.figure1);
            app.uipanel3.Title = 'Data Location';
            app.pushbutton2 = uibutton(app.uipanel3, 'push');
            app.pushbutton2.ButtonPushedFcn = createCallbackFcn(app, @pushbutton2_Callback, true);
            app.pushbutton2.Text = 'Select Folder';
            app.edit1 = uieditfield(app.uipanel3, 'text');
            app.edit1.ValueChangedFcn = createCallbackFcn(app, @edit1_Callback, true);
            app.edit1.Value = 'No Folder Selected';
            app.text7 = uilabel(app.uipanel3);
            app.text7.Text = ' ';
            app.uipanel5 = uipanel(app.figure1);
            app.uipanel5.Title = 'Plots';
            app.checkbox5 = uicheckbox(app.uipanel5);
            app.checkbox5.Tooltip = 'Unchecking this box will prevent all figures from displaying';
            app.checkbox5.Text = 'Overview 1';
            app.checkbox5.Value = true;
            app.checkbox9 = uicheckbox(app.uipanel5);
            app.checkbox9.Text = 'Noise Analysis';
            app.checkbox11 = uicheckbox(app.uipanel5);
            app.checkbox11.Text = 'Overview 2';
            app.uibuttongroup4 = uibuttongroup(app.figure1);
            app.uibuttongroup4.Title = 'Analysis';
            app.radiobutton4 = uiradiobutton(app.uibuttongroup4);
            app.radiobutton4.Text = 'Raw';
            app.radiobutton5 = uiradiobutton(app.uibuttongroup4);
            app.radiobutton5.Text = 'Cleaned';
            app.radiobutton5.Value = true;
            app.pushbutton3 = uibutton(app.figure1, 'push');
            app.pushbutton3.ButtonPushedFcn = createCallbackFcn(app, @pushbutton3_Callback, true);
            app.pushbutton3.Text = 'Close Figures';
            app.uipanel3_2 = uipanel(app.figure1);
            app.uipanel3_2.Title = 'Other';
            app.checkbox6 = uicheckbox(app.uipanel3_2);
            app.checkbox6.ValueChangedFcn = createCallbackFcn(app, @checkbox6_Callback, true);
            app.checkbox6.Text = 'Debug';
            app.uibuttongroup5 = uibuttongroup(app.figure1);
            app.uibuttongroup5.Title = 'Plotting';
            app.radiobutton7 = uiradiobutton(app.uibuttongroup5);
            app.radiobutton7.Text = 'Raw';
            app.radiobutton8 = uiradiobutton(app.uibuttongroup5);
            app.radiobutton8.Text = 'Cleaned';
            app.radiobutton8.Value = true;
        end
    end

    methods (Access = public)

        % Construct app
        function app = VOR_GUI_App

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.figure1)

            % Execute the startup function
            runStartupFcn(app, @VOR_GUI_OpeningFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end